classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        SaveButton                     matlab.ui.control.Button
        SpecifySpideryMeshButtonGroup  matlab.ui.container.ButtonGroup
        RadialLinesButton              matlab.ui.control.RadioButton
        VanishPointButton              matlab.ui.control.RadioButton
        RearWindowButton               matlab.ui.control.RadioButton
        CurrentPositionEditField       matlab.ui.control.EditField
        GridLayout2                    matlab.ui.container.GridLayout
        OpenButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % flags
        drawVP_flag     % check wether VP is defined and drawed
        drawRW_flag     % check wether inner Rectangle(Rear Window) is drawed
        drawRL_flag     %

        InputImage      % documentation of the Image
        
        % spider mesh
        VanishPoint     % Vanishing Point in size of 2x1 Vector

        OuterRect       % specify the four corners of outer rectangle with direction upleft-upright-downright-downleft in size of 2x4 Matriz
        OuterFixies     % 2x(radial_size*4) Matriz
        InnerRect       % specify the four corners of Rear Window with direction upleft-upright-downright-downleft in size of 2x4 Matriz
        InnerCorrespond % correspond points of InnerRect on the OuterRect based on the VP in size of 2x4 Matriz
        OuterCorrespond
    end
    
    properties (Access = public)
        draw_outer_rect     % control the drawing of outer rect
        draw_inner_rect     % control the drawing of inner rect
        draw_vp             % * of Vanish Point
        draw_radial         % * of Radial Lines

        radial_size
        select_process
    end
    
    % specify VanishPoint, InnerRect, OuterRect and RadialLines (function)
    methods (Access = private)
        
        % check if mouse is within axes
        function [new_point, isInAxes] = check_mouse_within_axes(app)
            isInAxes = true;
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if cp(1) < 0 % x-axis
                cp(1) = 0;
                isInAxes = false;
            elseif cp(1) > size(app.InputImage,2)
                cp(1) = size(app.InputImage,2);
                isInAxes = false;
            end
            if cp(2) < 0 % y-axis
                cp(2) = 0;
                isInAxes = false;
            elseif cp(2) > size(app.InputImage,1)
                cp(2) = size(app.InputImage,1);
                isInAxes = false;
            end
            new_point = cp;
        end
        
        % draw the initial vanishpoint and radiallines
        function specify_VanishPoint(app)
            % check wether the VP is drawed, if it is already drawed, get
            % its current position. And if its button is selected, set it
            % to interactable
            if app.drawVP_flag
                if app.select_process == 1
                    app.VanishPoint = app.draw_vp.Position';
                    app.update_RadialLines();
                    set(app.draw_vp, 'InteractionsAllowed', 'all');
                else
                    try
                        set(app.draw_vp, 'InteractionsAllowed', 'none');
                    catch
                    end
                end
                return;
            end
            % init check weheter the mouse is within outerrect and plot the
            % selection of VanishPoint
            [~, isInAxes] = app.check_mouse_within_axes();
            if ~isInAxes
                return;
            end
            app.draw_vp = drawpoint(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor'};
            ValueArray = {'VP', 0, 'white'};
            set(app.draw_vp, NameArray, ValueArray);

            % set the flag of VP, means it is drawed.
            app.drawVP_flag = true;
            app.update_RadialLines();
        end

        % draw the inner rectangle
        function specify_RearWindow(app)
            if app.drawRW_flag
                if app.select_process == 2
                    inner_rect_pos = app.draw_inner_rect.Position;
                    app.InnerRect = [[inner_rect_pos(1);inner_rect_pos(2)],...
                                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)],...
                                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)+inner_rect_pos(4)],...
                                     [inner_rect_pos(1);inner_rect_pos(2)+inner_rect_pos(4)]];
                    app.update_RadialLines();
                    set(app.draw_inner_rect, 'InteractionsAllowed', 'all');
                else
                    try
                        set(app.draw_inner_rect, 'InteractionsAllowed', 'none');
                    catch
                    end
                end
                return;
            end
            app.draw_inner_rect = drawrectangle(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'FaceSelectable'};
            ValueArray = {'IR', 0, 'white', 0, false};
            set(app.draw_inner_rect, NameArray, ValueArray);

            % set the flag of InnerRect as true means it has been drawn
            app.drawRW_flag = true;
        end
        
        function specify_RadialLines(app)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return;
            end
            if app.drawRL_flag
                if app.select_process == 3
                    % change VP Position based on trajectory of L2
                    set(app.draw_radial(end),'InteractionsAllowed','reshape');
                    while get(app.draw_radial(end),'Selected')
                        cp = app.UIAxes.CurrentPoint(1,1:2);                     
                        fixie_inner_3 = app.InnerRect(:,3);
                        fixie_inner_4 = app.InnerRect(:,4);
                        fixie_corres_3 = app.InnerCorrespond(:,3);
                        fixie_line_k = (fixie_inner_3(2)-fixie_corres_3(2))/(fixie_inner_3(1)-fixie_corres_3(1));
                        fixie_line_b = fixie_inner_3(2)-fixie_line_k*fixie_inner_3(1);
                        
                        new_line_k = (fixie_inner_4(2)-cp(2))/(fixie_inner_4(1)-cp(1));
                        new_line_b = fixie_inner_4(2) - new_line_k*fixie_inner_4(1);
        
                        app.VanishPoint = [(new_line_b-fixie_line_b)/(-new_line_k+fixie_line_k); new_line_k*(new_line_b-fixie_line_b)/(-new_line_k+fixie_line_k)+new_line_b];
                        set(app.draw_vp,'Position',app.VanishPoint')
                        app.update_RadialLines();
                    end
                else
                    try
                        set(app.draw_radial(end),'InteractionsAllowed','none');
                    catch
                    end
                end
                return;
            end
        end

        function update_RadialLines(app)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return;
            end
            try 
                delete(app.draw_radial);
            catch
            end
            app.drawRL_flag = false;
            % plot the radial lines
            app.draw_radial = [];
            app.InnerCorrespond = zeros(2,length(app.InnerRect));
            %%% need to be optimized
            for l = 1:length(app.OuterFixies)
                radial_line = [app.VanishPoint app.OuterFixies(:,l)]';
                app.draw_radial(l) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 0.5);
            end
            for m = 1:length(app.InnerRect)
                % calculate the Corresponding Points based on Current
                % VanishPoint and Current InnerRect
                [app.InnerCorrespond(:,m), ~] = app.correspond_point_to_outer_rect(app.VanishPoint, app.InnerRect(:,m));
                radial_line = [app.VanishPoint app.InnerCorrespond(:,m)]';
                app.draw_radial(l+m) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 2);
            end
            app.drawRL_flag = true;
        end
        
        function specify_OuterRectangle(app)
            app.OuterRect = [[0,0]',[size(app.InputImage,2),0]',[size(app.InputImage,2),size(app.InputImage,1)]',[0,size(app.InputImage,1)]'];
            app.draw_outer_rect = drawrectangle(app.UIAxes, 'Position', [0,0,size(app.InputImage,2),size(app.InputImage,1)]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'InteractionsAllowed'};
            ValueArray = {'OR', 0, 'white', 0, 'none',};
            set(app.draw_outer_rect, NameArray, ValueArray);

            % preparation of radiallines based on outer_fixies
            app.OuterFixies = zeros(2,app.radial_size*length(app.OuterRect));
            n = 1;
            for i = 1:app.radial_size
                for j = 1:length(app.OuterRect)
                    app.OuterFixies(:, n) = [round(i*app.OuterRect(1,j)/app.radial_size); app.OuterRect(2,j)];
                    app.OuterFixies(:, n+1) = [app.OuterRect(1,j); round(i*app.OuterRect(2,j)/app.radial_size)];
                    n = n + 2;
                end
            end
            app.OuterFixies = unique(app.OuterFixies', 'rows')';
        end
        
        function specify_Polygons(app, vp, innerrect)
            app.OuterCorrespond = zeros(2,length(innerrect));
            [last_inner,last_outer] = app.correspond_point_to_outer_rect(vp, innerrect(:,4));
            last_innerRect = innerrect(:,4);
            for i = 1:length(innerrect)
                [app.InnerCorrespond(:,i),app.OuterCorrespond(:,i)] = app.correspond_point_to_outer_rect(vp, innerrect(:,i));
                this_inner = app.InnerCorrespond(:,i);
                this_outer = app.OuterCorrespond(:,i);
                this_innerRect = innerrect(:,i);
                disp(last_outer);disp(last_inner);disp(last_innerRect);
                if this_inner(1) == last_outer(1) || this_inner(2) == last_outer(2) 
                    drawpolygon(app.UIAxes,'Position',[last_innerRect last_outer this_inner this_innerRect]','InteractionsAllowed','none','Color',[0 0.4470 0.7410]);
                elseif this_inner(1) == last_inner(1) || this_inner(2) == last_inner(2)
                    drawpolygon(app.UIAxes,'Position',[last_innerRect last_inner this_inner this_innerRect]','InteractionsAllowed','none','Color',[0.8500 0.3250 0.0980]);
                elseif this_outer(1) == last_inner(1) || this_outer(2) == last_inner(2)
                    drawpolygon(app.UIAxes,'Position',[last_innerRect last_inner this_outer this_innerRect]','InteractionsAllowed','none','Color',[0.9290 0.6940 0.1250]);
                elseif this_outer(1) == last_outer(1) || this_outer(2) == last_outer(2)
                    drawpolygon(app.UIAxes,'Position',[last_innerRect last_outer this_outer this_innerRect]','InteractionsAllowed','none','Color',[0.4940 0.1840 0.5560]);
                end
                last_inner = this_inner;
                last_outer = this_outer;
                last_innerRect = this_innerRect;
            end
            drawpolygon(app.UIAxes,'Position',innerrect','InteractionsAllowed','none','Color',[0.6350 0.0780 0.1840]);
        end
        % project the radiallines of innerrect on the outerrect
        function [CorrespondPoint, CorrespondPoint_Outer] = correspond_point_to_outer_rect(app, point, compared_point)
            % determine the zone of the point in comparison to the compared
            % point
            if point(1) == compared_point(1)
                if point(2) > compared_point(2)
                    CorrespondPoint = [point(1); 0];
                elseif point(2) < compared_point(2)
                    CorrespondPoint = [point(1); size(app.InputImage,2)];
                else
                    CorrespondPoint = point;
                end
                return;
            else
                k = (point(2)-compared_point(2))/(point(1)-compared_point(1));
                b = point(2)-k*point(1);
                if point(1) > compared_point(1)
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-0));
                    theta_2 = atan((compared_point(2)-0)/(compared_point(1)-0));
                    if phi < theta_1
                        % edge below
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                        % outer leftside
                        CorrespondPoint_Outer = [0; b];
                    elseif phi >= theta_1 && phi < 0
                        % edge leftside
                        CorrespondPoint = [0; b];
                        % outer below
                        CorrespondPoint_Outer = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    elseif phi >= 0 && phi < theta_2
                        % edge leftside
                        CorrespondPoint = [0; b];
                        % outer above
                        CorrespondPoint_Outer = [-b/k; 0];
                    else
                        % edge above
                        CorrespondPoint = [-b/k; 0];
                        % outer leftside
                        CorrespondPoint_Outer = [0; b];
                    end
                else
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-0)/(compared_point(1)-size(app.InputImage,2)));
                    theta_2 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-size(app.InputImage,2)));
                    if phi < theta_1
                        % edge above
                        CorrespondPoint = [-b/k; 0];
                        % outer rightside
                        CorrespondPoint_Outer = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                    elseif phi >= theta_1 && phi < 0
                        % edge rightside
                        CorrespondPoint = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                        % outer above
                        CorrespondPoint_Outer = [-b/k; 0];
                    elseif phi >= 0 && phi < theta_2
                        % edge rightside
                        CorrespondPoint = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                        % outer below
                        CorrespondPoint_Outer = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    else
                        % edge below
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                        % outer rightside
                        CorrespondPoint_Outer = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            
            app.radial_size = 3;
            app.drawVP_flag = false;
            app.drawRW_flag = false;
            app.drawRL_flag = false;
            app.select_process = 1;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            startupFcn(app);
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            imshow(app.InputImage,'Parent',app.UIAxes);
            app.specify_OuterRectangle();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            tf = strcmp(app.UIFigure.SelectionType, 'normal');
            if tf == 1 % left click of the mouse
                switch app.select_process
                    case 1
                        app.specify_VanishPoint();
                    case 2
                        app.specify_RearWindow();
                    case 3
                        app.specify_RadialLines();
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % update the VanishPoint
            if app.drawVP_flag
                app.specify_VanishPoint();
            end
            % update the InnerRect
            if app.drawRW_flag
                app.specify_RearWindow();
            end
        end

        % Selection changed function: SpecifySpideryMeshButtonGroup
        function SpecifySpideryMeshButtonGroupSelectionChanged(app, event)
            switch app.SpecifySpideryMeshButtonGroup.SelectedObject.Text
                case 'VanishPoint'
                    app.select_process = 1;
                case 'RearWindow'
                    app.select_process = 2;
                case 'RadialLines'
                    app.select_process = 3;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            save_VP = app.VanishPoint;
            save_Rect = app.InnerRect;
            sace_Corr = app.InnerCorrespond;
            app.specify_Polygons(save_VP,save_Rect);
            try
                set(app.CurrentPositionEditField,'Value',sprintf('VanishPoint: %.2f, %.2f\n InnenXY: %.2f, %.2f', save_VP(:),save_Rect(:,1)));
            catch
                set(app.CurrentPositionEditField,'Value',sprintf('Error'))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CurrentPositionEditField = uieditfield(app.GridLayout3, 'text');
            app.SpecifySpideryMeshButtonGroup = uibuttongroup(app.GridLayout3);
            app.SpecifySpideryMeshButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpecifySpideryMeshButtonGroupSelectionChanged, true);
            app.SpecifySpideryMeshButtonGroup.Title = 'Specify Spidery Mesh';
            app.RearWindowButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.RearWindowButton.Tag = 'R';
            app.RearWindowButton.Text = 'RearWindow';
            app.VanishPointButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.VanishPointButton.Tag = 'V';
            app.VanishPointButton.Text = 'VanishPoint';
            app.VanishPointButton.Value = true;
            app.RadialLinesButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.RadialLinesButton.Tag = 'R';
            app.RadialLinesButton.Text = 'RadialLines';
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end